classdef RobotScara2016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        FowardKinemeticParametersPanel  matlab.ui.container.Panel
        theta1Slider                    matlab.ui.control.Slider
        theta2Slider                    matlab.ui.control.Slider
        d3Slider                        matlab.ui.control.Slider
        theta4Slider                    matlab.ui.control.Slider
        theta1SliderLabel               matlab.ui.control.Label
        theta2SliderLabel               matlab.ui.control.Label
        d3SliderLabel                   matlab.ui.control.Label
        theta4SliderLabel               matlab.ui.control.Label
        Theta1EditField                 matlab.ui.control.NumericEditField
        Theta2EditField                 matlab.ui.control.NumericEditField
        d3EditField                     matlab.ui.control.NumericEditField
        Theta4EditField                 matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        a1 = 400;   %Link 1 length
        a2 = 250;   %Link 2 length
        d1 = 383.5; %Base height       
        d4 = 30;    %End Effector length
        
        theta1; flag1;  %flag = 0 means edit fild is used
        theta2; flag2;  %flag = 1 means slider field is used
        d3; flag3;
        theta4; flag4;
        
        A0_0 = [0 0 0 0 ; 0 0 0 0 ; 0 0 0 0; 0 0 0 0 ]';
        Tipbase = [50 0 0; 0 50 0; 0 0 50]'; % End of Unit vectors x, y, z of base
        
        %% Ploting variables, declare first so that when redraw, delete them
        Link1;  Link2;  Link3;  Link4;  j1; j2; j3; j4;
        Ox0; Oy0; Oz0;
        Ox1; Oy1; Oz1;
        Ox2; Oy2; Oz2;
        Ox3; Oy3; Oz3;
        Ox4; Oy4; Oz4;
        
    end
    
    methods (Access = private) 

        function A = DH(~, a, alpha, d, theta)
        A = [cos(theta) -cos(alpha)*sin(theta) sin(alpha)*sin(theta) a*cos(theta)
             sin(theta) cos(alpha)*cos(theta) -sin(alpha)*cos(theta) a*sin(theta)
                 0          sin(alpha)             cos(alpha)           d
                 0              0                      0                1 ];
        end
               
        function Linkn = PlotLinkn(app,HomogeneousMatA, HomogeneousMatB)
            OAx = HomogeneousMatA(1,4);
            OAy = HomogeneousMatA(2,4);
            OAz = HomogeneousMatA(3,4);
            OBx = HomogeneousMatB(1,4);
            OBy = HomogeneousMatB(2,4);
            OBz = HomogeneousMatB(3,4);          
            Linkn = plot3(app.UIAxes, [OAx OBx], [OAy OBy], [OAz OBz],'k','LineWidth',3);
        end
        
        function Link1 = PlotLink1(app,HomogeneousMatA, d1) % Because of the base height d1
        % link1 must first draw the vertical part then the horizontal part
            OAx = HomogeneousMatA(1,4);
            OAy = HomogeneousMatA(2,4);
            OAz = HomogeneousMatA(3,4);
            Link1 = plot3(app.UIAxes, [0 0], [0 0], [0 d1],'k','LineWidth',3);  % Draw vertical part
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            Link1 = plot3(app.UIAxes, [0 OAx], [0 OAy], [d1 OAz],'k','LineWidth',3);    % Draw hori part
        end
        
        function [Ox,Oy,Oz] = Plotcoordinates(app,UnitVecFrameN, HomogeneousMat)
        % Basically connect the point where the base Origin is translated
        % to and then conect the base with tips of unit vector after they
        % are rotated and translated
            HomogeneousMat = HomogeneousMat(:,4);
            HomogeneousMat(4) = [];     %% Get Translation vector
            HomogeneousMat = HomogeneousMat';   %Transpose into 1 row, 3 col vector
            drawArrow = @(x,y,z, color) quiver3(app.UIAxes, x(1), y(1), z(1), ...
                                    x(2)-x(1),y(2)-y(1),z(2)-z(1), 1, ...
                                    'Color', color, 'MaxHeadSize', 3,'LineWidth', 3);
            for i = 1 : 3 
                pts= [HomogeneousMat; UnitVecFrameN(:,i)'];
                x = pts(:,1);
                y = pts(:,2);                    
                z = pts(:,3);            
                switch i
                    case 1
                        Ox = drawArrow(x, y, z, 'r');
                    case 2
                        Oy = drawArrow(x, y, z,'g');
                    case 3
                        Oz = drawArrow(x, y, z,'b');
                end
            end
        end
        
        function NewCoordinates = TransOp(~,Coordinates, HomogeneousMat)
        %% New coordinate = Old coordinate * Homogeneous Matrix
            Coordinates = [Coordinates; 1 1 1];
            NewCoordinates = HomogeneousMat*Coordinates;
            NewCoordinates = NewCoordinates(1:3,1:3);
        end
        
        
        function s1= plot_joint1(app,baseheight)   
            [x, y, z] = sphere;
            a = [0  0   baseheight  10];
            s1=surf(app.UIAxes,x*a(1,4)+a(1,1),y*a(1,4)+a(1,2),z*a(1,4)+a(1,3),'EdgeColor','y');
        end
        
        function s1= plot_joint(app,HomogeneousMat)
            [x, y, z] = sphere;
            a = [HomogeneousMat(1,4) HomogeneousMat(2, 4) ...
                 HomogeneousMat(3,4) 10];
            s1=surf(app.UIAxes,x*a(1,4)+a(1,1),y*a(1,4)+a(1,2),z*a(1,4)+a(1,3),'EdgeColor','y');
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = [ app.a1 app.a2 0 0   ];      
            alpha = [ 0    pi    0     0   ];
            d = [ app.d1     0    0    app.d4];           
            theta = [ 0 pi/2 0  0];
            
            A0_1 = DH(app, a(1), alpha(1), d(1), theta(1) );
            A1_2 = DH(app, a(2), alpha(2), d(2), theta(2) );
            A2_3 = DH(app, a(3), alpha(3), d(3), theta(3) );
            A3_4 = DH(app, a(4), alpha(4), d(4), theta(4) );
            
            A0_2 = A0_1*A1_2;
            A0_3 = A0_2*A2_3;
            A0_4 = A0_3*A3_4;     
            
        %% Draw Robot
            axis(app.UIAxes,[-1000 1000 -1000 1000 0 600]);
            % Plot Link 1
            app.Link1 = PlotLink1(app,A0_1, app.d1);
            % Plot joint 1
            app.j1 = plot_joint1(app,app.d1);
            % Plot Link 2
            app.Link2 = PlotLinkn(app,A0_1,A0_2);
            % Plot joint 2
            app.j2 = plot_joint(app,A0_2);
            % Plot Link 3 
            app.Link3 = PlotLinkn(app,A0_2,A0_3);
            % Plot joint 3
            app.j3 = plot_joint(app,A0_4);
            % Plot Link 4 
            app.Link4 = PlotLinkn(app,A0_3,A0_4);
            % Plot joint 4
            app.j4 = plot_joint(app,A0_4);
        %% Plot Coordinates

            %% Plot  Coor base
            [app.Ox0, app.Oy0, app.Oz0] =Plotcoordinates(app,app.Tipbase,app.A0_0);
            %% Plot Coor 1
            TipCoordi0_1 = TransOp(app,app.Tipbase,A0_1); % Find End of Unit vectors x, y, z of Frame 1 to Base
            [app.Ox1, app.Oy1, app.Oz1] = Plotcoordinates(app,TipCoordi0_1,A0_1);
            %% Plot Coor 2
            TipCoordi0_2 = TransOp(app,app.Tipbase,A0_2); % Find End of Unit vectors x, y, z of Frame 2 to Base
            [app.Ox2, app.Oy2, app.Oz2] = Plotcoordinates(app,TipCoordi0_2,A0_2);
            %% Plot Coor 3
            TipCoordi0_3 = TransOp(app,app.Tipbase,A0_3); % Find End of Unit vectors x, y, z of Frame 3 to Base
            [app.Ox3, app.Oy3, app.Oz3] =Plotcoordinates(app,TipCoordi0_3,A0_3);
            %% Plot Coor 4
            TipCoordi0_4 = TransOp(app,app.Tipbase,A0_4); % Find End of Unit vectors x, y, z of Frame 4 to Base
            [app.Ox4, app.Oy4, app.Oz4] =Plotcoordinates(app,TipCoordi0_4,A0_4);
            text(app.UIAxes,-600,600,500,'red: x axis','Color','r');
            text(app.UIAxes,-600,600,550,'blue: z axis','Color','b');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        %% Update theta1
            %% Synchronize Editfield and Slider
            if app.flag1 == 0
                app.theta1Slider.Value = app.Theta1EditField.Value;
            else
                app.Theta1EditField.Value = app.theta1Slider.Value;
            end
            app.theta1 = app.Theta1EditField.Value;
            app.theta1 = app.theta1*pi/180; %Convert to Radian

        %% Update theta2
            %% Synchronize Editfield and Slider
            if app.flag2 == 0
                app.theta2Slider.Value = app.Theta2EditField.Value;
            else
                app.Theta2EditField.Value = app.theta2Slider.Value;
            end
            app.theta2 = app.Theta2EditField.Value;
            app.theta2 = app.theta2*pi/180; %Convert to Radian
        %% Update d3
            %% Synchronize Editfield and Slider
            if app.flag3 == 0
                app.d3Slider.Value = app.d3EditField.Value;
            else
                app.d3EditField.Value = app.d3Slider.Value;
            end
            app.d3 = app.d3EditField.Value;
  
        %% Update theta4
            %% Synchronize Editfield and Slider
            if app.flag4 == 0
                app.theta4Slider.Value = app.Theta4EditField.Value;
            else
                app.Theta4EditField.Value = app.theta4Slider.Value;
            end
            app.theta4 = app.Theta4EditField.Value;
            app.theta4 = app.theta4*pi/180; %Convert to Radian
        %% Forward Kinemetic
            a = [ app.a1 app.a2 0 0   ];     
            alpha = [ 0    pi    0     0   ];
            d = [ app.d1     0    app.d3    app.d4];           
            theta = [ app.theta1 app.theta2 0  app.theta4];
        
            A0_1 = DH(app, a(1), alpha(1), d(1), theta(1) );
            A1_2 = DH(app, a(2), alpha(2), d(2), theta(2) );
            A2_3 = DH(app, a(3), alpha(3), d(3), theta(3) );
            A3_4 = DH(app, a(4), alpha(4), d(4), theta(4) );
                        
            A0_2 = A0_1*A1_2;
            A0_3 = A0_2*A2_3;
            A0_4 = A0_3*A3_4;
        
        %% Delete Previous position
            delete(app.Link1);    delete(app.Link2);    delete(app.Link3);    delete(app.Link4);
            delete(app.j1);   delete(app.j2);   delete(app.j3);   delete(app.j4);
            delete(app.Ox0);   delete(app.Oy0);   delete(app.Oz0);   
            delete(app.Ox1);   delete(app.Oy1);   delete(app.Oz1);
            delete(app.Ox2);   delete(app.Oy2);   delete(app.Oz2);
            delete(app.Ox3);   delete(app.Oy3);   delete(app.Oz3);
            delete(app.Ox4);   delete(app.Oy4);   delete(app.Oz4);
            

        %% Redraw Robot        
            % Plot Link 1
            app.Link1 = PlotLink1(app,A0_1, app.d1);
            % Plot joint 1
            app.j1 = plot_joint1(app,app.d1);
            % Plot Link 2
            app.Link2 = PlotLinkn(app,A0_1,A0_2);
            % Plot joint 2
            app.j2 = plot_joint(app,A0_2);
            % Plot Link 3 
            app.Link3 = PlotLinkn(app,A0_2,A0_3);
            % Plot joint 3
            app.j3 = plot_joint(app,A0_3);
            % Plot Link 4 
            app.Link4 = PlotLinkn(app,A0_3,A0_4);
            % Plot joint 4
            app.j4 = plot_joint(app,A0_4);
            
            %% Plot Coordinates

            % %plot  Coor base
            [app.Ox0, app.Oy0, app.Oz0] =Plotcoordinates(app,app.Tipbase,app.A0_0);
            
            TipCoordi0_1 = TransOp(app,app.Tipbase,A0_1); % Find End of Unit vectors x, y, z of Frame 1 to Base
            [app.Ox1, app.Oy1, app.Oz1] = Plotcoordinates(app,TipCoordi0_1,A0_1);
             
            TipCoordi0_2 = TransOp(app,app.Tipbase,A0_2); % Find End of Unit vectors x, y, z of Frame 2 to Base
            [app.Ox2, app.Oy2, app.Oz2] = Plotcoordinates(app,TipCoordi0_2,A0_2);
             
            TipCoordi0_3 = TransOp(app,app.Tipbase,A0_3); % Find End of Unit vectors x, y, z of Frame 3 to Base
            [app.Ox3, app.Oy3, app.Oz3] =Plotcoordinates(app,TipCoordi0_3,A0_3);
             
            TipCoordi0_4 = TransOp(app,app.Tipbase,A0_4); % Find End of Unit vectors x, y, z of Frame 4 to Base
            [app.Ox4, app.Oy4, app.Oz4] =Plotcoordinates(app,TipCoordi0_4,A0_4);        
        end

        % Value changed function: theta1Slider
        function theta1SliderValueChanged(app, event)
            app.flag1 = 1;
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.flag1 = 0;           
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            app.flag2 = 0;            
        end

        % Value changed function: theta2Slider
        function theta2SliderValueChanged(app, event)
            app.flag2 = 1;            
        end

        % Value changed function: d3EditField
        function d3EditFieldValueChanged(app, event)
            app.flag3 = 0;            
        end

        % Value changed function: d3Slider
        function d3SliderValueChanged(app, event)
            app.flag3 = 1;            
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            app.flag4 = 0;            
        end

        % Value changed function: theta4Slider
        function theta4SliderValueChanged(app, event)
            app.flag4 = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.FowardKinemeticParametersPanel = uipanel(app.UIFigure);
            app.FowardKinemeticParametersPanel.Title = 'Foward Kinemetic Parameters';
            app.theta1Slider = uislider(app.FowardKinemeticParametersPanel);
            app.theta1Slider.Limits = [-180 180];
            app.theta1Slider.ValueChangedFcn = createCallbackFcn(app, @theta1SliderValueChanged, true);
            app.theta2Slider = uislider(app.FowardKinemeticParametersPanel);
            app.theta2Slider.Limits = [-145 145];
            app.theta2Slider.ValueChangedFcn = createCallbackFcn(app, @theta2SliderValueChanged, true);
            app.d3Slider = uislider(app.FowardKinemeticParametersPanel);
            app.d3Slider.Limits = [0 353];
            app.d3Slider.ValueChangedFcn = createCallbackFcn(app, @d3SliderValueChanged, true);
            app.theta4Slider = uislider(app.FowardKinemeticParametersPanel);
            app.theta4Slider.Limits = [0 90];
            app.theta4Slider.ValueChangedFcn = createCallbackFcn(app, @theta4SliderValueChanged, true);
            app.theta1SliderLabel = uilabel(app.FowardKinemeticParametersPanel);
            app.theta1SliderLabel.Text = 'theta1';
            app.theta2SliderLabel = uilabel(app.FowardKinemeticParametersPanel);
            app.theta2SliderLabel.Text = 'theta2';
            app.d3SliderLabel = uilabel(app.FowardKinemeticParametersPanel);
            app.d3SliderLabel.Text = 'd3';
            app.theta4SliderLabel = uilabel(app.FowardKinemeticParametersPanel);
            app.theta4SliderLabel.Text = 'theta4';
            app.Theta1EditField = uieditfield(app.FowardKinemeticParametersPanel, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditField = uieditfield(app.FowardKinemeticParametersPanel, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.d3EditField = uieditfield(app.FowardKinemeticParametersPanel, 'numeric');
            app.d3EditField.ValueChangedFcn = createCallbackFcn(app, @d3EditFieldValueChanged, true);
            app.Theta4EditField = uieditfield(app.FowardKinemeticParametersPanel, 'numeric');
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RobotScara2016

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end